classdef MovControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure         % UI Figure
        LabelKnob        matlab.ui.control.Label  % Motor 1
        motor1           matlab.ui.control.Knob   % [0 180]
        LabelKnob2       matlab.ui.control.Label  % Motor 2
        motor2           matlab.ui.control.Knob   % [0 180]
        posicao          matlab.ui.control.UIAxes % Posição
        velocidade       matlab.ui.control.UIAxes % Velocidade
        aceleracao       matlab.ui.control.UIAxes % Aceleração
        enviar_movimento matlab.ui.control.Button % Enviar
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global posi;
            global posf;
            
            posi_1 = 90;
            posi_2 = 90;
            
            posf_1 = 0;
            posf_2 = 0;
        end

        % motor1 value changed function
        function motor1ValueChanged(app)
            global motor1;
            
            motor1 = app.motor1.Value;
        end

        % motor2 value changed function
        function motor2ValueChanged(app)
            global motor2;
            motor2 = app.motor2.Value;
        end

        % enviar_movimento button pushed function
        function enviar_movimentoButtonPushed(app)
            load('Pol5OrdValues.mat','tf_5','vi_5','vf_5','ai_5','af_5');
            posf_1 = round(motor1)
            posf_2 = round(motor2)
            % Gerando os vetores de posição, velocidade e aceleração
            for i=1:2
                if i==1
                    theta= [posi_1; vi_5;ai_5; posf_1; vf_5; af_5];
                end
                if i==2
                    theta= [posi_2; vi_5;ai_5; posf_2; vf_5; af_5];
                end
                a=[1 0 0 0 0 0; 0 1 0 0 0 0;0 0 2 0 0 0;1 tf_5 tf_5^2 tf_5^3 tf_5^4 tf_5^5;0 1 2*tf_5 3*tf_5^2 4*tf_5^3 5*tf_5^4;0 0 2 6*tf_5 12*tf_5^2 20*tf_5^3];
                c=inv(a)*theta;
                ta=tf_5/100;
                
                for t=0:99
                    theta1_t(t+1)= c(1)+c(2)*(t*ta)+c(3)*((t*ta)^2)+c(4)*((t*ta)^3)+ (c(5)*(t*ta)^4)+(c(6)*(t*ta)^5);
                    v1_t(t+1)=c(2)+2*c(3)*(t*ta)+(3*c(4)*(t*ta)^2)+(4*c(5)*(ta*t)^3)+(5*c(6)*(t*ta)^4);
                    a1_t(t+1)= 2*c(3)+6*c(4)*(t*ta)+(12*c(5)*(t*ta)^2)+(20*c(6)*(t*ta)^3);
                end
                
                for t=0:99
                    theta2_t(t+1)= c(1)+c(2)*(t*ta)+c(3)*((t*ta)^2)+c(4)*((t*ta)^3)+ (c(5)*(t*ta)^4)+(c(6)*(t*ta)^5);
                    v2_t(t+1)=c(2)+2*c(3)*(t*ta)+(3*c(4)*(t*ta)^2)+(4*c(5)*(ta*t)^3)+(5*c(6)*(t*ta)^4);
                    a2_t(t+1)= 2*c(3)+6*c(4)*(t*ta)+(12*c(5)*(t*ta)^2)+(20*c(6)*(t*ta)^3);
                end
                
            end
            
            % Arredondando os valores do vetor posição para envio
            for i=1:100
                theta1_t(i)=round(theta1_t(i));
                theta2_t(i)=round(theta2_t(i));
            end
            
            % Enviando via comunicação serial para o arduino
            delete(instrfindall);
            x=serial('COM3','BAUD', 9600);
            
            % Plotando os gráficos de posição, velocidade e aceleração
            g=linspace(0,tf_5,100);
            % Plot Primeiro Motor
            plot(app.posicao,g,theta1_t)
            plot(app.posicao,g,v1_t)
            plot(app.posicao,g,a1_t)
            % Plot Segundo Motor
            plot(app.posicao,g,theta2_t)
            plot(app.posicao,g,v2_t)
            plot(app.posicao,g,a2_t)
            
            fopen(x);
            pause(2.0)
            fwrite(x,tf_5);
            for i=1:100
                fwrite(x,theta1_t(i));
                fwrite(x,theta2_t(i));
            end
            fclose(x);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelKnob = uilabel(app.UIFigure);
            app.LabelKnob.Text = 'Motor 1';
            app.motor1 = uiknob(app.UIFigure, 'continuous');
            app.motor1.Limits = [0 180];
            app.motor1.ValueChangedFcn = createCallbackFcn(app, @motor1ValueChanged);
            app.motor1.Value = 90;
            app.LabelKnob2 = uilabel(app.UIFigure);
            app.LabelKnob2.Text = 'Motor 2';
            app.motor2 = uiknob(app.UIFigure, 'continuous');
            app.motor2.Limits = [0 180];
            app.motor2.ValueChangedFcn = createCallbackFcn(app, @motor2ValueChanged);
            app.motor2.Value = 90;
            app.posicao = uiaxes(app.UIFigure);
            title(app.posicao, 'Posição');
            xlabel(app.posicao, 'Segundos');
            ylabel(app.posicao, 'Graus');
            app.velocidade = uiaxes(app.UIFigure);
            title(app.velocidade, 'Velocidade');
            xlabel(app.velocidade, 'Segundos');
            ylabel(app.velocidade, 'Graus');
            app.aceleracao = uiaxes(app.UIFigure);
            title(app.aceleracao, 'Aceleração');
            xlabel(app.aceleracao, 'Segundos');
            ylabel(app.aceleracao, 'Graus');
            app.enviar_movimento = uibutton(app.UIFigure, 'push');
            app.enviar_movimento.ButtonPushedFcn = createCallbackFcn(app, @enviar_movimentoButtonPushed);
            app.enviar_movimento.Text = 'Enviar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MovControl()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end