classdef Prototipo_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure         % UI Figure
        buttonPOLY3 matlab.ui.control.Button % Polynomial Third-Order
        buttonPOLY5 matlab.ui.control.Button % Polynomial Fifth-Order
        buttonLSPB  matlab.ui.control.Button % Parabolic Blend
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.101;
            height = screenHeight*0.18;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % buttonLSPB button pushed function
        function buttonLSPBButtonPushed(app)
            Trajetoria;
        end

        % buttonEXIT button pushed function
        function buttonEXITButtonPushed(app)
            
        end

        % buttonPOLY3 button pushed function
        function buttonPOLY3ButtonPushed(app)
            Trajetoria_pol3
        end

        % buttonPOLY5 button pushed function
        function buttonPOLY5ButtonPushed(app)
            Trajetoria_pol5;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.buttonPOLY3 = uibutton(app.UIFigure, 'push');
            app.buttonPOLY3.ButtonPushedFcn = createCallbackFcn(app, @buttonPOLY3ButtonPushed);
            app.buttonPOLY3.Text = 'Polynomial Third-Order';
            app.buttonPOLY5 = uibutton(app.UIFigure, 'push');
            app.buttonPOLY5.ButtonPushedFcn = createCallbackFcn(app, @buttonPOLY5ButtonPushed);
            app.buttonPOLY5.Text = 'Polynomial Fifth-Order';
            app.buttonLSPB = uibutton(app.UIFigure, 'push');
            app.buttonLSPB.ButtonPushedFcn = createCallbackFcn(app, @buttonLSPBButtonPushed);
            app.buttonLSPB.Text = 'Parabolic Blend';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Prototipo_1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end