classdef Trajetoria < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure            % Parabolic Blend
        grafico         matlab.ui.control.UIAxes    % Comportamento
        Button          matlab.ui.control.Button    % Plotar
        LabelEditField  matlab.ui.control.Label     % Posição Inicial
        qi              matlab.ui.control.EditField
        LabelEditField2 matlab.ui.control.Label     % Posição Final
        qf              matlab.ui.control.EditField
        LabelEditField3 matlab.ui.control.Label     % Tempo
        tf              matlab.ui.control.EditField
        LabelEditField4 matlab.ui.control.Label     % Velocidade
        v               matlab.ui.control.EditField % 0
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.5;
            height = screenHeight*0.35;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            q = 0; qd = 0; qdd = 0;
            s = 0; sd = 0; sdd = 0;
            
            qi = str2num(app.qi.Value);
            qf = str2num(app.qf.Value);
            tf = str2num(app.tf.Value);
            V = str2num(app.v.Value);
            
            
            if V == 0
                
                %Aceleração
                qcdd = 4 *(qf - qi) / ( tf ^ 2)+3;% (4.7)
                tc = tf/2 - (1/2)*sqrt((tf^2*qcdd-4*(qf-qi))/qcdd ) %(4.6)
                
                for t = 0.01:0.01:tf;
                    if  t  <= tc,
                        q = [q, qi + 1/2*qcdd*t^2];
                        qd = [qd, qcdd*t];
                        qdd = [qdd, qcdd];
                    end
                    if  (t > tc) & t <=(tf - tc)
                        q = [q,  qi + qcdd*tc *(t - tc/2)];
                        qd = [qd, qcdd*tc*(1)];
                        qdd = [qdd, 0];
                    end
                    if (t > (tf - tc)) & (t <= tf)
                        q = [q, qf - 1/2 * qcdd *(tf - t)^2];
                        qd = [qd, qcdd*(tf-t)];
                        qdd = [qdd, -qcdd];
                    end
                end
                % Plot
                t = [0: 0.01: tf];
                plot(app.grafico,t, q,t,qd,t,qdd);
                
                
            end
            
            if V ~= 0
                
                tb = (qi - qf + V*tf)/V
                a = V/tb
                
                if tb > 0
                    
                    for  t = 0.01:0.01:tf;
                        if t <= tb
                            s =[s, qi + a/2*t^2];
                            sd =[sd, a*t];
                            sdd =[sdd, a];
                        elseif t <= (tf-tb)
                            s =[s, (qf+qi-V*tf)/2 + V*t];
                            sd =[sd, V];
                            sdd =[sdd, 0];
                        else
                            s =[s, qf - a/2*tf^2 + a*tf*t - a/2*t^2];
                            sd =[sd, a*tf - a*t];
                            sdd =[sdd, -a];
                        end
                    end
                    % Plot
                    t = [0: 0.01: tf];
                    plot(app.grafico,t, s,t,sd,t,sdd);
                    
                else
                    f = app.UIFigure;
                    uialert(f,'Valores de velocidade e tempo imcompatíveis','Value Error');
                    t = [0: 0.01: tf];
                    s = 0; sd = 0; sdd = 0;
                    plot(app.grafico,t, s,t,sd,t,sdd);
                end
                
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Parabolic Blend';
            setAutoResize(app, app.UIFigure, true)
            app.grafico = uiaxes(app.UIFigure);
            title(app.grafico, 'Comportamento');
            xlabel(app.grafico, 'Tempo');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Plotar';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Posição Inicial';
            app.qi = uieditfield(app.UIFigure, 'text');
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'Posição Final';
            app.qf = uieditfield(app.UIFigure, 'text');
            app.LabelEditField3 = uilabel(app.UIFigure);
            app.LabelEditField3.Text = 'Tempo';
            app.tf = uieditfield(app.UIFigure, 'text');
            app.LabelEditField4 = uilabel(app.UIFigure);
            app.LabelEditField4.Text = 'Velocidade';
            app.v = uieditfield(app.UIFigure, 'text');
            app.v.Value = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Trajetoria()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end