classdef Trajetoria_pol3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure            % Polynomial Third-Order
        grafico         matlab.ui.control.UIAxes    % Comportamento
        buttonPlot      matlab.ui.control.Button    % Plotar
        LabelEditField  matlab.ui.control.Label     % Posição Inicial
        edPosInicial    matlab.ui.control.EditField
        LabelEditField2 matlab.ui.control.Label     % Posição Final
        edPosFinal      matlab.ui.control.EditField
        LabelEditField3 matlab.ui.control.Label     % Tempo Final
        edTFinal        matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.5;
            height = screenHeight*0.4;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % buttonPlot button pushed function
        function buttonPlotButtonPushed(app)
            
            close all
            
            q0 = str2num(app.edPosInicial.Value);
            qf = str2num(app.edPosFinal.Value);
            tf = str2num(app.edTFinal.Value);
            
            q=0; qd=0;  qdd=0;
            
            c0 = q0;    %Posição Inical
            c1 = 0;     %Posição Final
            
            syms c2 c3
            eqn1 = c0+(c1*tf)+(c2*(tf^2))+(c3*(tf^3)) == qf;
            eqn2 = c1+(2*c2*tf)+(3*c3*(tf^2)) == 0;
            
            sol = solve([eqn1, eqn2], [c2,c3]);
            c2 = sol.c2        %Velocidade
            c3 = sol.c3        %Aceleração
            
            
            for t = 0.01:0.01:tf;
                
                q = [q, c0+(c1*t)+(c2*t^2)+(c3*t^3)];
                qd = [qd, c1+(2*c2*t)+(3*c3*t^2)];
                qdd = [qdd, 2*c2+(6*c3*t)];
                
            end
            
            % Plots
            t = [0: 0.01: tf];
            plot(app.grafico,t, q,t,qd,t,qdd);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Polynomial Third-Order';
            setAutoResize(app, app.UIFigure, true)
            app.grafico = uiaxes(app.UIFigure);
            title(app.grafico, 'Comportamento');
            xlabel(app.grafico, 'Tempo');
            app.buttonPlot = uibutton(app.UIFigure, 'push');
            app.buttonPlot.ButtonPushedFcn = createCallbackFcn(app, @buttonPlotButtonPushed);
            app.buttonPlot.Text = 'Plotar';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Posição Inicial';
            app.edPosInicial = uieditfield(app.UIFigure, 'text');
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'Posição Final';
            app.edPosFinal = uieditfield(app.UIFigure, 'text');
            app.LabelEditField3 = uilabel(app.UIFigure);
            app.LabelEditField3.Text = 'Tempo Final';
            app.edTFinal = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Trajetoria_pol3()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end