classdef Trajetoria_pol5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure            % Polynomial Fifth-Order
        grafico         matlab.ui.control.UIAxes    % Comportamento
        LabelEditField  matlab.ui.control.Label     % Posição Inicial
        edPosInicial    matlab.ui.control.EditField
        LabelEditField2 matlab.ui.control.Label     % Posição Final
        edPosFinal      matlab.ui.control.EditField
        LabelEditField3 matlab.ui.control.Label     % Tempo
        edTFinal        matlab.ui.control.EditField
        LabelEditField4 matlab.ui.control.Label     % Aceleração
        edAce           matlab.ui.control.EditField
        edPlotar        matlab.ui.control.Button    % Plotar
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.5;
            height = screenHeight*0.35;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % edPlotar button pushed function
        function edPlotarButtonPushed(app)
            close all
            
            q=0; qd=0;  qdd=0;
            
            q0 = str2num(app.edPosInicial.Value);
            qf = str2num(app.edPosFinal.Value);
            tf = str2num(app.edTFinal.Value);
            Ai = str2num(app.edAce.Value);
            
            c0 = q0;
            c1 = 0;
            c2 = 0.5*Ai;
            
            syms c3 c4 c5
            eqn1 = c0+(c1*tf)+(c2*(tf^2))+(c3*(tf^3))+(c4*(tf^4))+(c5*(tf^5)) == qf;
            eqn2 = c1+(2*c2*tf)+(3*c3*(tf^2))+(4*c4*(tf^3))+(5*c5*(tf^4)) == 0;
            eqn3 = 2*c2+(6*c3*tf)+(12*c4*(tf^2))+(20*c5*(tf^3)) == -0.5*Ai;
            
            sol = solve([eqn1, eqn2, eqn3], [c3,c4,c5]);
            
            c3 = sol.c3
            c4 = sol.c4
            c5 = sol.c5
            
            
            for t = 0.01:0.01:tf;
                
                q = [q, c0+(c1*t)+(c2*t^2)+(c3*t^3)+(c4*(t^4))+(c5*(t^5))];
                qd = [qd, c1+(2*c2*t)+(3*c3*t^2)+(4*c4*(t^3))+(5*c5*(t^4))];
                qdd = [qdd, 2*c2+(6*c3*t)+(12*c4*(t^2))+(20*c5*(t^3))];
                
            end
            
            % Plots
            t = [0: 0.01: tf];
            plot(app.grafico,t, q,t,qd,t,qdd);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Polynomial Fifth-Order';
            setAutoResize(app, app.UIFigure, true)
            app.grafico = uiaxes(app.UIFigure);
            title(app.grafico, 'Comportamento');
            xlabel(app.grafico, 'Tempo');
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Posição Inicial';
            app.edPosInicial = uieditfield(app.UIFigure, 'text');
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'Posição Final';
            app.edPosFinal = uieditfield(app.UIFigure, 'text');
            app.LabelEditField3 = uilabel(app.UIFigure);
            app.LabelEditField3.Text = 'Tempo';
            app.edTFinal = uieditfield(app.UIFigure, 'text');
            app.LabelEditField4 = uilabel(app.UIFigure);
            app.LabelEditField4.Text = 'Aceleração';
            app.edAce = uieditfield(app.UIFigure, 'text');
            app.edPlotar = uibutton(app.UIFigure, 'push');
            app.edPlotar.ButtonPushedFcn = createCallbackFcn(app, @edPlotarButtonPushed);
            app.edPlotar.Text = 'Plotar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Trajetoria_pol5()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end